classdef visualize_results_labeler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Image                     matlab.ui.control.Image
        currentImgNbr             matlab.ui.control.NumericEditField
        zz                        matlab.ui.control.Label
        MaxImgNbr                 matlab.ui.control.Label
        goNext                    matlab.ui.control.Button
        goBack                    matlab.ui.control.Button
        PosButton                 matlab.ui.control.Button
        NegButton                 matlab.ui.control.Button
        ZeroButton                matlab.ui.control.Button
        UnsureButton_2            matlab.ui.control.Button
        AnnotationLabel           matlab.ui.control.Label
        currentAnnotation         matlab.ui.control.Label
        AutojumptonextCheckBox    matlab.ui.control.CheckBox
        CurrentannotatorLabel     matlab.ui.control.Label
        CurrentannotatorDropDown  matlab.ui.control.DropDown
        whoisannotating           matlab.ui.control.Label
        FigurewindowCheckBox      matlab.ui.control.CheckBox
        GreenStartRedEndBlueSlowspeedYellowHighspeedLabel  matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
        LabelerNamesLabel         matlab.ui.control.Label
        LabelsByNames             matlab.ui.control.Label
        nameOfImage               matlab.ui.control.Label
        NextconflictButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        image_names % Contains image names from excel-sheet
        labels % Contains labels of selected annotator
        imageFileFormat = "" 
        imagePath = "tools/images/"
        fig = 0
        annotators
        all_labels
        conflict % Is there not a majority vote?
    end
    
    methods (Access = private)
        
        function change_image(app,new)
            %disp image
            if isnumeric(app.image_names{new})
                app.Image.ImageSource = strcat(app.imagePath,num2str(app.image_names{new}),app.imageFileFormat);
                if ~(app.fig == 0)
                    app.fig;
                    hold on;
                    imshow(imread(app.Image.ImageSource))
                end
                app.nameOfImage.Text = num2str(app.image_names{new});
            else
                app.Image.ImageSource = strcat(app.imagePath,app.image_names{new},app.imageFileFormat); 
                if ~(app.fig == 0)
                    app.fig;
                    hold on;
                    imshow(imread(app.Image.ImageSource))
                end
                app.nameOfImage.Text = app.image_names{new};
            end
            
            %disp annotation
            if isnumeric(app.labels{new})
                app.currentAnnotation.Text = "---"; %no annotation exist yet
            else
                app.currentAnnotation.Text = app.labels{new};
            end
           
            %disp image nbr in stack
            app.currentImgNbr.Value = new;


            %disp all labels
            temp = {};
            for labelerNbr = 2:length(app.annotators)              
                 temp_labeler = app.all_labels{labelerNbr-1};
                 try
                    temp{labelerNbr-1} = temp_labeler{new};
                 catch %if there are no labels an error will be thrown...
                     temp{labelerNbr-1} = '---';
                 end
            end
            app.LabelsByNames.Text = temp;
            %disp label diagram
                % pos=1, then neg zero unsure NaN
            %x = [1 2 3 4 5]
            y = [0 0 0 0 0];
            for labelerNbr = 2:length(app.annotators)
                if strcmp(app.LabelsByNames.Text{labelerNbr-1},'pos')
                    y(1) = y(1)+1;
                elseif strcmp(app.LabelsByNames.Text{labelerNbr-1},'neg')
                    y(2) = y(2)+1;
                elseif strcmp(app.LabelsByNames.Text{labelerNbr-1},'zero')
                    y(3) = y(3)+1;
                elseif strcmp(app.LabelsByNames.Text{labelerNbr-1},'unsure')
                    y(4) = y(4)+1;
                else
                    y(5) = y(5)+1;
                end
            end
            cla(app.UIAxes)
            hold(app.UIAxes,'on')
            bar(app.UIAxes,1,y(1),'b')
            bar(app.UIAxes,2,y(2),'r')
            bar(app.UIAxes,3,y(3),'y')
            bar(app.UIAxes,4,y(4),'k')
            bar(app.UIAxes,5,y(5),'w')
            hold(app.UIAxes,'off')
            
            if any(y == length(app.annotators)-1)
                app.conflict = false;
            else
                app.conflict = true;
            end            
        end
        
        function label_image(app,label)
            % Insert to label array in program
            app.labels{app.currentImgNbr.Value} = label;
            % Display label
            app.currentAnnotation.Text = app.labels{app.currentImgNbr.Value};

            % Save this single label to file, saving continuously
            excel_column = char('A' + find(strcmp(app.CurrentannotatorDropDown.Value,app.CurrentannotatorDropDown.Items)));
            excel_row = num2str(app.currentImgNbr.Value + 1);
            excel_cell = strcat(excel_column,excel_row);
            writematrix(label,"tools/labels.xlsx","range",excel_cell)
            
            if app.AutojumptonextCheckBox.Value
                app.goNextButtonPushed()
            end
        end
        
        function unfreeze(app)
            %executed once after annotator is selected
            app.currentImgNbr.Enable = true;
            app.goBack.Enable = true;
            app.goNext.Enable = true;
            %app.PosButton.Enable = true;
            %app.NegButton.Enable = true;
            %app.ZeroButton.Enable = true;
            %app.UnsureButton_2.Enable = true;
            app.AutojumptonextCheckBox.Enable = true;
            app.Image.Enable = true;
            app.FigurewindowCheckBox.Enable = true;
            app.NextconflictButton.Enable = true;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath tools
            table = readtable('tools/labels.xlsx');
            
            % Get annotators
            app.annotators = table.Properties.VariableNames;
            % Set them in dropdown menu
            app.CurrentannotatorDropDown.Items = app.annotators;
            app.LabelerNamesLabel.Text = app.annotators(2:end);
            app.CurrentannotatorDropDown.Items = [" " app.CurrentannotatorDropDown.Items(2:end) "New"];
            
            % Get image file names
            app.image_names = table.images;
            % Set max image nbr
            app.MaxImgNbr.Text = num2str(length(app.image_names));
            app.currentImgNbr.Limits = [1 length(app.image_names)];
            
            % Image is only showed when user chooses annotator
                % (CurrentannotatorDropDownValueChanged)
                
            % Store all labels in variable
            %allLabels = 
                
            if isnumeric(app.image_names)
                app.image_names = num2cell(app.image_names);
            end
            
            app.UIAxes.YLim = [0 length(app.annotators)-1];
            app.UIAxes.YTick = 0:length(app.annotators)-1;
            
        end

        % Value changed function: currentImgNbr
        function currentImgNbrValueChanged(app, event)
            value = app.currentImgNbr.Value;
            change_image(app,value)
        end

        % Button pushed function: goBack
        function goBackButtonPushed(app, event)
            if app.currentImgNbr.Value > 1
                app.currentImgNbr.Value = app.currentImgNbr.Value -1; %probably done twice somewhere
                change_image(app,app.currentImgNbr.Value)
            end
        end

        % Button pushed function: goNext
        function goNextButtonPushed(app, event)
            if app.currentImgNbr.Value < length(app.image_names)
                app.currentImgNbr.Value = app.currentImgNbr.Value +1;
                change_image(app,app.currentImgNbr.Value)
            end
        end

        % Button pushed function: NegButton
        function NegButtonPushed(app, event)
            label_image(app,"neg")
        end

        % Button pushed function: ZeroButton
        function ZeroButtonPushed(app, event)
            label_image(app,"zero")
        end

        % Button pushed function: PosButton
        function PosButtonPushed(app, event)
            label_image(app,"pos")
        end

        % Button pushed function: UnsureButton_2
        function UnsureButton_2Pushed(app, event)
            label_image(app,"unsure")
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'rightarrow'
                    app.goNextButtonPushed(app)
                case 'leftarrow'
                    app.goBackButtonPushed(app)
            end
        end

        % Value changed function: CurrentannotatorDropDown
        function CurrentannotatorDropDownValueChanged(app, event)
            %This function is executed after startupfcn when user chooses
            %annotator name. Also if the user later chooses another name. 
            
            % Remove first option, only executed once
            if strcmp(app.CurrentannotatorDropDown.Items(1)," ")
                app.CurrentannotatorDropDown.Items(1) = [];
                app.unfreeze()
            end
            
            % If user wants to create another annotator
            if strcmp(app.CurrentannotatorDropDown.Value,"New")
                answer = inputdlg("Please input your name. The program will then restart.","Add new user");
                if length(answer) == 0
                    labeler;
                    delete(app)
                    return
                end
                
                excel_column = char('A' + find(strcmp(app.CurrentannotatorDropDown.Value,app.CurrentannotatorDropDown.Items)));
                excel_cell = strcat(excel_column,"1");
                writematrix(answer{1},"tools/labels.xlsx","range",excel_cell)
                
                labeler;
                delete(app)
                return
            
            else

                table = readtable('tools/labels.xlsx');         
                app.labels = table2cell(table(:,app.CurrentannotatorDropDown.Value));
    %             app.CurrentannotatorDropDown.Enable = false;
    
                    % Save all labels in temp memory
                for annotatorNbr = 2:length(app.annotators)
                    app.all_labels{annotatorNbr - 1} = table.(app.annotators{annotatorNbr});
                end     
                
                % Show first image
                change_image(app,app.currentImgNbr.Value)
                
                app.whoisannotating.Text = "(by " + app.CurrentannotatorDropDown.Value + ")";


            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % ToDo: Spara preferenser? Senaste nummer, annotator typ...
            delete(app)
        end

        % Value changed function: FigurewindowCheckBox
        function FigurewindowCheckBoxValueChanged(app, event)
            value = app.FigurewindowCheckBox.Value;
            
            if value
                app.fig = figure;
                imshow(imread(app.Image.ImageSource))
            else
                app.fig = 0;
            end
            
        end

        % Button pushed function: NextconflictButton
        function NextconflictButtonPushed(app, event)
            if app.currentImgNbr.Value < length(app.image_names)
                app.currentImgNbr.Value = app.currentImgNbr.Value +1;
                change_image(app,app.currentImgNbr.Value)

                if app.conflict == false
                    app.NextconflictButtonPushed();
                end
               
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.Enable = 'off';
            app.currentImgNbr = uieditfield(app.UIFigure, 'numeric');
            app.currentImgNbr.Limits = [0 Inf];
            app.currentImgNbr.ValueChangedFcn = createCallbackFcn(app, @currentImgNbrValueChanged, true);
            app.currentImgNbr.Enable = 'off';
            app.currentImgNbr.Value = 1;
            app.zz = uilabel(app.UIFigure);
            app.zz.Text = '/';
            app.MaxImgNbr = uilabel(app.UIFigure);
            app.MaxImgNbr.Text = 'max';
            app.goNext = uibutton(app.UIFigure, 'push');
            app.goNext.ButtonPushedFcn = createCallbackFcn(app, @goNextButtonPushed, true);
            app.goNext.Enable = 'off';
            app.goNext.Text = '>';
            app.goBack = uibutton(app.UIFigure, 'push');
            app.goBack.ButtonPushedFcn = createCallbackFcn(app, @goBackButtonPushed, true);
            app.goBack.Enable = 'off';
            app.goBack.Text = '<';
            app.PosButton = uibutton(app.UIFigure, 'push');
            app.PosButton.ButtonPushedFcn = createCallbackFcn(app, @PosButtonPushed, true);
            app.PosButton.Enable = 'off';
            app.PosButton.Text = 'Pos';
            app.NegButton = uibutton(app.UIFigure, 'push');
            app.NegButton.ButtonPushedFcn = createCallbackFcn(app, @NegButtonPushed, true);
            app.NegButton.Enable = 'off';
            app.NegButton.Text = 'Neg';
            app.ZeroButton = uibutton(app.UIFigure, 'push');
            app.ZeroButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroButtonPushed, true);
            app.ZeroButton.Enable = 'off';
            app.ZeroButton.Text = 'Zero';
            app.UnsureButton_2 = uibutton(app.UIFigure, 'push');
            app.UnsureButton_2.ButtonPushedFcn = createCallbackFcn(app, @UnsureButton_2Pushed, true);
            app.UnsureButton_2.Enable = 'off';
            app.UnsureButton_2.Text = 'Unsure';
            app.AnnotationLabel = uilabel(app.UIFigure);
            app.AnnotationLabel.Visible = 'off';
            app.AnnotationLabel.Text = 'Annotation:';
            app.currentAnnotation = uilabel(app.UIFigure);
            app.currentAnnotation.Visible = 'off';
            app.currentAnnotation.Text = 'None';
            app.AutojumptonextCheckBox = uicheckbox(app.UIFigure);
            app.AutojumptonextCheckBox.Enable = 'off';
            app.AutojumptonextCheckBox.Text = 'Auto jump to next';
            app.AutojumptonextCheckBox.Value = true;
            app.CurrentannotatorLabel = uilabel(app.UIFigure);
            app.CurrentannotatorLabel.Text = 'Current annotator:';
            app.CurrentannotatorDropDown = uidropdown(app.UIFigure);
            app.CurrentannotatorDropDown.Items = {};
            app.CurrentannotatorDropDown.ValueChangedFcn = createCallbackFcn(app, @CurrentannotatorDropDownValueChanged, true);
            app.CurrentannotatorDropDown.Tooltip = {''};
            app.CurrentannotatorDropDown.Value = {};
            app.whoisannotating = uilabel(app.UIFigure);
            app.whoisannotating.Visible = 'off';
            app.whoisannotating.Text = '-';
            app.FigurewindowCheckBox = uicheckbox(app.UIFigure);
            app.FigurewindowCheckBox.ValueChangedFcn = createCallbackFcn(app, @FigurewindowCheckBoxValueChanged, true);
            app.FigurewindowCheckBox.Enable = 'off';
            app.FigurewindowCheckBox.Text = 'Figure window';
            app.GreenStartRedEndBlueSlowspeedYellowHighspeedLabel = uilabel(app.UIFigure);
            app.GreenStartRedEndBlueSlowspeedYellowHighspeedLabel.Text = {'Green  = Start'; 'Red     = End'; 'Blue    = Slow speed'; 'Yellow = High speed'};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Labels')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, 'Count')
            app.UIAxes.XLim = [0 6];
            app.UIAxes.XTick = [1 2 3 4 5];
            app.UIAxes.XTickLabel = {'pos'; 'neg'; 'zero'; 'unsure'; 'nolabel'};
            app.LabelerNamesLabel = uilabel(app.UIFigure);
            app.LabelerNamesLabel.Text = 'Names';
            app.LabelsByNames = uilabel(app.UIFigure);
            app.LabelsByNames.Text = '';
            app.nameOfImage = uilabel(app.UIFigure);
            app.NextconflictButton = uibutton(app.UIFigure, 'push');
            app.NextconflictButton.ButtonPushedFcn = createCallbackFcn(app, @NextconflictButtonPushed, true);
            app.NextconflictButton.Enable = 'off';
            app.NextconflictButton.Text = 'Next conflict >>';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = visualize_results_labeler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end